classdef buttonTableDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UITable      matlab.ui.control.Table
        ButtonGroup  matlab.ui.container.ButtonGroup
        Button       matlab.ui.control.RadioButton
        Button2      matlab.ui.control.RadioButton
        Button3      matlab.ui.control.RadioButton
    end


    methods (Access = private)
    
        % Helper function to retrieve button number from the button text
        function value = getButtonNumber(app)
            
            button = app.ButtonGroup.SelectedObject.Text;
            
            if strcmpi(button,'button')
                value = 1;
            elseif strcmpi(button,'button2')
                value = 2;
            else
                value = 3;
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create data and store in app
            app.UIFigure.UserData = randi(99,3);
            
            % Initialize table
            ButtonGroupSelectionChanged(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % Get new value
            value = getButtonNumber(app);
            
            % Reset table and fill in new row
            app.UITable.Data = zeros(3);
            app.UITable.Data(value,1:3) = app.UIFigure.UserData(value,:);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; ''};
            app.UITable.RowName = {};
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = buttonTableDemo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end